classdef Clint_Wilson_A18891433 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CalculationPanel                matlab.ui.container.Panel
        d_maxinEditField                matlab.ui.control.NumericEditField
        d_maxinLabel                    matlab.ui.control.Label
        PlotDeformedShapeButton         matlab.ui.control.Button
        CalculateMaxDeflectionButton    matlab.ui.control.Button
        ClearButton                     matlab.ui.control.Button
        PlotOptionsPanel                matlab.ui.container.Panel
        HoldOnCheckBox                  matlab.ui.control.CheckBox
        GridOnCheckBox                  matlab.ui.control.CheckBox
        InputValuesPanel                matlab.ui.container.Panel
        wplfEditField                   matlab.ui.control.NumericEditField
        wplfEditFieldLabel              matlab.ui.control.Label
        CaseEvenlyDistributedLoadLabel  matlab.ui.control.Label
        DistancetoLeftEndftEditField    matlab.ui.control.NumericEditField
        DistancetoLeftEndftEditFieldLabel  matlab.ui.control.Label
        PlbsEditField                   matlab.ui.control.NumericEditField
        PlbsEditFieldLabel              matlab.ui.control.Label
        CasePointLoadLabel              matlab.ui.control.Label
        LoadCaseDropDown                matlab.ui.control.DropDown
        LoadCaseDropDownLabel           matlab.ui.control.Label
        Iin4EditField                   matlab.ui.control.NumericEditField
        Iin4EditFieldLabel              matlab.ui.control.Label
        LftEditField                    matlab.ui.control.NumericEditField
        LftLabel                        matlab.ui.control.Label
        EksiEditField                   matlab.ui.control.NumericEditField
        EksiLabel                       matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: EksiEditField
        function EksiEditFieldValueChanged(app, event)
            value = app.EksiEditField.Value;
            
        end

        % Value changed function: LftEditField
        function LftEditFieldValueChanged(app, event)
            value = app.LftEditField.Value;
            
        end

        % Value changed function: Iin4EditField
        function Iin4EditFieldValueChanged(app, event)
            value = app.Iin4EditField.Value;
            
        end

        % Value changed function: GridOnCheckBox
        function GridOnCheckBoxValueChanged(app, event)
            value = app.GridOnCheckBox.Value;
            if value == 1
                grid(app.UIAxes, 'on')
            else
                grid(app.UIAxes, 'off')
            end
        end

        % Value changed function: HoldOnCheckBox
        function HoldOnCheckBoxValueChanged(app, event)
            value = app.HoldOnCheckBox.Value;
            if value == 1
                hold(app.UIAxes, 'on')
            else
                hold(app.UIAxes, 'off')
            end
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            cla(app.UIAxes)
            
            app.d_maxinEditField.Value = 0;

        end

        % Button pushed function: CalculateMaxDeflectionButton
        function CalculateMaxDeflectionButtonPushed(app, event)
           E = app.EksiEditField.Value;
           I = app.Iin4EditField.Value;
           L = app.LftEditField.Value;

           loadcase = app.LoadCaseDropDown.Value;

           P = app.PlbsEditField.Value;
           w = app.wplfEditField.Value;
           b = app.DistancetoLeftEndftEditField.Value;

            
            switch loadcase
               
               case 'Point Load'
                   if b > L
                       uialert(app.UIFigure, 'Distance to Left End can not be larger than Length', 'Invalid Input');
                       return
                   end

                   d_max = (P * b * (L^2 - b^2)^(3/2)) / (9 * sqrt(3) * L * E * I);


               case 'Evenly Distributed'
                   d_max = (5 * w * L^4) / (384 * E * I);


            end

            app.d_maxinEditField.Value = d_max;

        end

        % Value changed function: LoadCaseDropDown
        function LoadCaseDropDownValueChanged(app, event)
            value = app.LoadCaseDropDown.Value;
            
        end

        % Value changed function: PlbsEditField
        function PlbsEditFieldValueChanged(app, event)
            value = app.PlbsEditField.Value;
            
        end

        % Value changed function: wplfEditField
        function wplfEditFieldValueChanged(app, event)
            value = app.wplfEditField.Value;
            
        end

        % Value changed function: DistancetoLeftEndftEditField
        function DistancetoLeftEndftEditFieldValueChanged(app, event)
            value = app.DistancetoLeftEndftEditField.Value;
            
        end

        % Value changed function: d_maxinEditField
        function d_maxinEditFieldValueChanged(app, event)
            value = app.d_maxinEditField.Value;
            
        end

        % Button pushed function: PlotDeformedShapeButton
        function PlotDeformedShapeButtonPushed(app, event)
           E = app.EksiEditField.Value;
           I = app.Iin4EditField.Value;
           L = app.LftEditField.Value;

           loadcase = app.LoadCaseDropDown.Value;

           P = app.PlbsEditField.Value;
           w = app.wplfEditField.Value;
           b = app.DistancetoLeftEndftEditField.Value;

           E = E * 1000;
           L = L * 12;
           b = b * 12;
           w = w / 12;

           x = linspace(0, L, 100);
           y = zeros(size(x));


           switch loadcase

               case 'Point Load'

                   if b > L
                       uialert(app.UIFigure, 'Distance to Left End can not be larger than Length', 'Invalid Input');
                       return
                   end

                   a = L - b;
                   
                   for k = 1:length(x)
                       if x(k) <= b
                           y(k) = (P * a * x(k) / (6 * L * E * I)) * (L^2 - a^2 - x(k)^2);

                       else
                          y(k) = (P * b * (L - x(k)) / (6 * L * E * I)) * ...
                              (L^2 - b^2 - (L - x(k))^2);
                       end
                   end

               case 'Evenly Distributed'
                    y = (w .* x .* (L^3 - 2*L*x.^2 + x.^3)) ./ (24*E*I);
           end
            
           
           plot(app.UIAxes, x, -y, 'LineWidth', 2)
      

           title(app.UIAxes, 'Simply Supported Beam Deflection')
           xlabel(app.UIAxes, 'Distance From Left End [in]')
           ylabel(app.UIAxes, 'Deflection [in]')


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Simply Supported Beam Deflection')
            xlabel(app.UIAxes, 'Distance From Left End [in]')
            ylabel(app.UIAxes, 'Deflection [in]')
            zlabel(app.UIAxes, 'Z')
            app.InputValuesPanel = uipanel(app.UIFigure);
            app.InputValuesPanel.Title = 'Input Values';
            app.EksiLabel = uilabel(app.InputValuesPanel);
            app.EksiLabel.Text = 'E [ksi]';
            app.EksiEditField = uieditfield(app.InputValuesPanel, 'numeric');
            app.EksiEditField.ValueChangedFcn = createCallbackFcn(app, @EksiEditFieldValueChanged, true);
            app.LftLabel = uilabel(app.InputValuesPanel);
            app.LftLabel.Text = 'L [ft]';
            app.LftEditField = uieditfield(app.InputValuesPanel, 'numeric');
            app.LftEditField.ValueChangedFcn = createCallbackFcn(app, @LftEditFieldValueChanged, true);
            app.Iin4EditFieldLabel = uilabel(app.InputValuesPanel);
            app.Iin4EditFieldLabel.Text = 'I [in^4]';
            app.Iin4EditField = uieditfield(app.InputValuesPanel, 'numeric');
            app.Iin4EditField.ValueChangedFcn = createCallbackFcn(app, @Iin4EditFieldValueChanged, true);
            app.LoadCaseDropDownLabel = uilabel(app.InputValuesPanel);
            app.LoadCaseDropDownLabel.Text = 'Load Case';
            app.LoadCaseDropDown = uidropdown(app.InputValuesPanel);
            app.LoadCaseDropDown.Items = {'Point Load', 'Evenly Distributed'};
            app.LoadCaseDropDown.ValueChangedFcn = createCallbackFcn(app, @LoadCaseDropDownValueChanged, true);
            app.LoadCaseDropDown.Value = 'Point Load';
            app.CasePointLoadLabel = uilabel(app.InputValuesPanel);
            app.CasePointLoadLabel.Text = 'Case: Point Load';
            app.PlbsEditFieldLabel = uilabel(app.InputValuesPanel);
            app.PlbsEditFieldLabel.Text = 'P [lbs]';
            app.PlbsEditField = uieditfield(app.InputValuesPanel, 'numeric');
            app.PlbsEditField.ValueChangedFcn = createCallbackFcn(app, @PlbsEditFieldValueChanged, true);
            app.DistancetoLeftEndftEditFieldLabel = uilabel(app.InputValuesPanel);
            app.DistancetoLeftEndftEditFieldLabel.Text = 'Distance to Left End [ft] ';
            app.DistancetoLeftEndftEditField = uieditfield(app.InputValuesPanel, 'numeric');
            app.DistancetoLeftEndftEditField.ValueChangedFcn = createCallbackFcn(app, @DistancetoLeftEndftEditFieldValueChanged, true);
            app.CaseEvenlyDistributedLoadLabel = uilabel(app.InputValuesPanel);
            app.CaseEvenlyDistributedLoadLabel.Text = 'Case: Evenly Distributed Load';
            app.wplfEditFieldLabel = uilabel(app.InputValuesPanel);
            app.wplfEditFieldLabel.Text = 'w [plf]';
            app.wplfEditField = uieditfield(app.InputValuesPanel, 'numeric');
            app.wplfEditField.ValueChangedFcn = createCallbackFcn(app, @wplfEditFieldValueChanged, true);
            app.PlotOptionsPanel = uipanel(app.UIFigure);
            app.PlotOptionsPanel.Title = 'Plot Options';
            app.GridOnCheckBox = uicheckbox(app.PlotOptionsPanel);
            app.GridOnCheckBox.ValueChangedFcn = createCallbackFcn(app, @GridOnCheckBoxValueChanged, true);
            app.GridOnCheckBox.Text = 'Grid On';
            app.HoldOnCheckBox = uicheckbox(app.PlotOptionsPanel);
            app.HoldOnCheckBox.ValueChangedFcn = createCallbackFcn(app, @HoldOnCheckBoxValueChanged, true);
            app.HoldOnCheckBox.Text = 'Hold On';
            app.CalculationPanel = uipanel(app.UIFigure);
            app.CalculationPanel.Title = 'Calculation';
            app.ClearButton = uibutton(app.CalculationPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.CalculateMaxDeflectionButton = uibutton(app.CalculationPanel, 'push');
            app.CalculateMaxDeflectionButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateMaxDeflectionButtonPushed, true);
            app.CalculateMaxDeflectionButton.Text = 'Calculate Max Deflection';
            app.PlotDeformedShapeButton = uibutton(app.CalculationPanel, 'push');
            app.PlotDeformedShapeButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDeformedShapeButtonPushed, true);
            app.PlotDeformedShapeButton.Text = 'Plot Deformed Shape';
            app.d_maxinLabel = uilabel(app.CalculationPanel);
            app.d_maxinLabel.Text = 'd_max [in]';
            app.d_maxinEditField = uieditfield(app.CalculationPanel, 'numeric');
            app.d_maxinEditField.ValueChangedFcn = createCallbackFcn(app, @d_maxinEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Clint_Wilson_A18891433

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end